classdef ControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ScanControlPanel                matlab.ui.container.Panel
        OffsetmVKnobLabel               matlab.ui.control.Label
        OffsetmVKnob                    matlab.ui.control.Knob
        AmplitudemVKnobLabel            matlab.ui.control.Label
        AmplitudemVKnob                 matlab.ui.control.Knob
        EnableScanSwitchLabel           matlab.ui.control.Label
        EnableScanSwitch                matlab.ui.control.Switch
        ScanDurationmsEditFieldLabel    matlab.ui.control.Label
        ScanDurationmsEditField         matlab.ui.control.NumericEditField
        RoutescantoDropDownLabel        matlab.ui.control.Label
        RoutescantoDropDown             matlab.ui.control.DropDown
        AmplitudeField                  matlab.ui.control.NumericEditField
        OffsetField                     matlab.ui.control.NumericEditField
        FilteringSettingsPanel          matlab.ui.container.Panel
        Log2ofavgsSpinnerLabel          matlab.ui.control.Label
        Log2ofavgsSpinner               matlab.ui.control.Spinner
        dtsEditFieldLabel               matlab.ui.control.Label
        dtsEditField                    matlab.ui.control.NumericEditField
        BWHzEditFieldLabel              matlab.ui.control.Label
        BWHzEditField                   matlab.ui.control.NumericEditField
        DataCollectionPanel             matlab.ui.container.Panel
        FIFO1DropDownLabel              matlab.ui.control.Label
        FIFO1DropDown                   matlab.ui.control.DropDown
        FIFO2DropDownLabel              matlab.ui.control.Label
        FIFO2DropDown                   matlab.ui.control.DropDown
        PID1Panel                       matlab.ui.container.Panel
        EnableSwitchLabel               matlab.ui.control.Label
        EnableSwitch                    matlab.ui.control.Switch
        PolaritySwitchLabel             matlab.ui.control.Label
        PolaritySwitch                  matlab.ui.control.Switch
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        ProportionalGainEditField       matlab.ui.control.NumericEditField
        IntegralGainEditFieldLabel      matlab.ui.control.Label
        IntegralGainEditField           matlab.ui.control.NumericEditField
        DerivativeGainEditFieldLabel    matlab.ui.control.Label
        DerivativeGainEditField         matlab.ui.control.NumericEditField
        DivisorEditFieldLabel           matlab.ui.control.Label
        DivisorEditField                matlab.ui.control.NumericEditField
        RealKpEditField                 matlab.ui.control.NumericEditField
        RealKiEditField                 matlab.ui.control.NumericEditField
        RealKdEditField                 matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        UpperLimitEditField             matlab.ui.control.NumericEditField
        SetPointEditField_2Label        matlab.ui.control.Label
        SetPointEditField               matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel        matlab.ui.control.Label
        LowerLimitEditField             matlab.ui.control.NumericEditField
        PID2Panel                       matlab.ui.container.Panel
        EnableSwitch_2Label             matlab.ui.control.Label
        EnableSwitch_2                  matlab.ui.control.Switch
        PolaritySwitch_2Label           matlab.ui.control.Label
        PolaritySwitch_2                matlab.ui.control.Switch
        ProportionalGainEditField_2Label  matlab.ui.control.Label
        ProportionalGainEditField_2     matlab.ui.control.NumericEditField
        IntegralGainEditField_2Label    matlab.ui.control.Label
        IntegralGainEditField_2         matlab.ui.control.NumericEditField
        DerivativeGainEditField_2Label  matlab.ui.control.Label
        DerivativeGainEditField_2       matlab.ui.control.NumericEditField
        DivisorEditField_2Label         matlab.ui.control.Label
        DivisorEditField_2              matlab.ui.control.NumericEditField
        RealKpEditField_2               matlab.ui.control.NumericEditField
        RealKiEditField_2               matlab.ui.control.NumericEditField
        RealKdEditField_2               matlab.ui.control.NumericEditField
        UpperLimitEditField_2Label      matlab.ui.control.Label
        UpperLimitEditField_2           matlab.ui.control.NumericEditField
        SetPointEditField_3Label        matlab.ui.control.Label
        SetPointEditField_2             matlab.ui.control.NumericEditField
        LowerLimitEditField_2Label      matlab.ui.control.Label
        LowerLimitEditField_2           matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        ApplicationSettingsPanel        matlab.ui.container.Panel
        AutoUpdateSwitchLabel           matlab.ui.control.Label
        AutoUpdateSwitch                matlab.ui.control.Switch
        UploadButton                    matlab.ui.control.Button
        FetchButton                     matlab.ui.control.Button
        RetrievescandataSwitchLabel     matlab.ui.control.Label
        RetrievescandataSwitch          matlab.ui.control.Switch
        FetchDataButton                 matlab.ui.control.Button
        PlotUpdateTimesEditFieldLabel   matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        PlotUpdateTimesEditField        matlab.ui.control.NumericEditField
        XAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        YAxisDropDownLabel              matlab.ui.control.Label
        YAxisDropDown                   matlab.ui.control.DropDown
        YLowerLimEditFieldLabel         matlab.ui.control.Label
        YLowerLimEditField              matlab.ui.control.NumericEditField
        YUpperLimEditFieldLabel         matlab.ui.control.Label
        YUpperLimEditField              matlab.ui.control.NumericEditField
        PlotDisplayDropDown             matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        servo                           LaserServo % LaserServo control
        autoUpdate % Description
        int_timer % Description
        plotType % Description
    end
    
    methods (Access = private)
        
        function results = updateServo(app)
            if strcmpi(app.autoUpdate,'On') && strcmpi(app.RetrievescandataSwitch.Value,'Off')
                app.servo.autoset.upload;
            end
            app.updateDisplay;
        end
        
        function results = updateDisplay(app)
            %
            % Shorten names
            %
            s = app.servo;
            scan = s.scan;
            pid = s.pid;
            %
            % Filtering settings
            %
            app.Log2ofavgsSpinner.Value = s.log2Avgs.value;
            app.dtsEditField.Value = s.dt();
            app.BWHzEditField.Value = 1/(2*s.dt());
            %
            % Data collection
            %
            app.FIFO1DropDown.Value = s.fifoRoute(1).value;
            app.FIFO2DropDown.Value = s.fifoRoute(2).value;
            %
            % Scan control
            %
            app.OffsetmVKnob.Value = scan.offset.value*1e3;
            app.AmplitudemVKnob.Value = scan.amplitude.value*1e3;
            app.OffsetField.Value = scan.offset.value*1e3;
            app.AmplitudeField.Value = s.scan.amplitude.value*1e3;
            if scan.enable.value
                app.EnableScanSwitch.Value = 'On';
            else
                app.EnableScanSwitch.Value = 'Off';
            end
            app.ScanDurationmsEditField.Value = scan.duration*1e3;
            if pid(1).scanEnable.value == 1 && pid(2).scanEnable.value == 0
                app.RoutescantoDropDown.Value = 'PID1';
            elseif pid(1).scanEnable.value == 0 && pid(2).scanEnable.value == 1
                app.RoutescantoDropDown.Value = 'PID2';
            elseif pid(1).scanEnable.value == 0 && pid(2).scanEnable.value == 0
                app.RoutescantoDropDown.Value = 'None';
            elseif pid(1).scanEnable.value == 1 && pid(2).scanEnable.value == 1
                app.RoutescantoDropDown.Value = 'Both';
            end
            %
            % PID1 settings
            %
            if pid(1).enable.value
                app.EnableSwitch.Value = 'On';
            else
                app.EnableSwitch.Value = 'Off';
            end
            if pid(1).polarity.value == 0
                app.PolaritySwitch.Value = 'Negative';
            else
                app.PolaritySwitch.Value = 'Positive';
            end
            app.ProportionalGainEditField.Value = pid(1).Kp.value;
            app.IntegralGainEditField.Value = pid(1).Ki.value;
            app.DerivativeGainEditField.Value = pid(1).Kd.value;
            app.DivisorEditField.Value = pid(1).divisor.value;
            app.UpperLimitEditField.Value = pid(1).upperLimit.value;
            app.LowerLimitEditField.Value = pid(1).lowerLimit.value;
            app.SetPointEditField.Value = pid(1).control.value;
            [Kp,Ki,Kd] = pid(1).calculateRealGains;
            app.RealKpEditField.Value = Kp;
            app.RealKiEditField.Value = Ki;
            app.RealKdEditField.Value = Kd;
            %
            % PID2 settings
            %
            if pid(2).enable.value
                app.EnableSwitch_2.Value = 'On';
            else
                app.EnableSwitch_2.Value = 'Off';
            end
            if pid(2).polarity.value == 0
                app.PolaritySwitch_2.Value = 'Negative';
            else
                app.PolaritySwitch_2.Value = 'Positive';
            end
            app.ProportionalGainEditField_2.Value = pid(2).Kp.value;
            app.IntegralGainEditField_2.Value = pid(2).Ki.value;
            app.DerivativeGainEditField_2.Value = pid(2).Kd.value;
            app.DivisorEditField_2.Value = pid(2).divisor.value;
            app.UpperLimitEditField_2.Value = pid(2).upperLimit.value;
            app.LowerLimitEditField_2.Value = pid(2).lowerLimit.value;
            app.SetPointEditField_2.Value = pid(2).control.value;
            [Kp,Ki,Kd] = pid(2).calculateRealGains;
            app.RealKpEditField_2.Value = Kp;
            app.RealKiEditField_2.Value = Ki;
            app.RealKdEditField_2.Value = Kd;
            
        end
        
        function results = updatePlot(app)
            app.servo.autoset.upload;
            app.servo.getScanData;
%             app.servo.fetch;
%             app.updateDisplay;
            data = app.servo.data;
            if strcmpi(app.PlotDisplayDropDown.Value,'fifo 1')
                data = data(:,1);
            elseif strcmpi(app.PlotDisplayDropDown.Value,'fifo 2')
                data = data(:,2);
            end
            if strcmpi(app.PlotDropDown.Value,'time')
                plot(app.UIAxes,app.servo.t,data,'.-');
                set(app.UIAxes,'Xscale','lin','YScale','lin');
                app.UIAxes.XLabel.String = 'Time [s]';
                app.UIAxes.YLabel.String = 'Signal [V]';
                set(app.UIAxes,'Xlim',[app.servo.t(1),app.servo.t(end)]);
            elseif strcmpi(app.PlotDropDown.Value,'volts')
                [t,v] = app.servo.scan.estimateScan();
                vinterp = interp1(t,v,app.servo.t,'linear');
                plot(app.UIAxes,vinterp,data,'.-');
                set(app.UIAxes,'Xscale','lin','YScale','lin');
                app.UIAxes.XLabel.String = 'Scan [V]';
                app.UIAxes.YLabel.String = 'Signal [V]';
                set(app.UIAxes,'XLim',[min(vinterp),max(vinterp)]);
            elseif strcmpi(app.PlotDropDown.Value,'psd')
                dt = app.servo.t(2) - app.servo.t(1);
                N = size(app.servo.data,1);
                f = 1/dt*(0:(N-1))/N;
                f = f(1:floor(N/2))';
                Y = fft(detrend(data,1),N,1);
                Y = Y(1:floor(N/2),:);
                Y(1,:) = Y(1,:)/N;
                Y(2:end,:) = 2*Y(2:end,:)/N;
                Y = Y/sqrt(2);
                P = abs(Y).^2/(f(2) - f(1));
                plot(app.UIAxes,f,P,'.-');
                set(app.UIAxes,'Xscale',app.XAxisDropDown.Value,'YScale',app.YAxisDropDown.Value);
                set(app.UIAxes,'Xlim',[f(2),f(end)]);
                app.UIAxes.XLabel.String = 'Freq [Hz]';
                app.UIAxes.YLabel.String = 'PSD [V^2/Hz]';
            end
            
            set(app.UIAxes,'XGrid','on','YGrid','on')
            lims = [app.YLowerLimEditField.Value,app.YUpperLimEditField.Value];
            set(app.UIAxes,'YLim',sort(lims));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, servo)
            if nargin < 2
                app.servo = LaserServo;
                app.servo.setDefaults;
            else
                app.servo = servo;
            end
            app.updateDisplay;
            
            
        end

        % Value changed function: Log2ofavgsSpinner
        function Log2ofavgsSpinnerValueChanged(app, event)
            value = app.Log2ofavgsSpinner.Value;
            app.servo.log2Avgs.set(value);
            app.updateServo;
        end

        % Value changed function: FIFO1DropDown
        function FIFO1DropDownValueChanged(app, event)
            value = app.FIFO1DropDown.Value;
            app.servo.fifoRoute(1).set(value);
            app.updateServo;
        end

        % Value changed function: FIFO2DropDown
        function FIFO2DropDownValueChanged(app, event)
            value = app.FIFO2DropDown.Value;
            app.servo.fifoRoute(2).set(value);
            app.updateServo;
        end

        % Value changed function: OffsetmVKnob
        function OffsetmVKnobValueChanged(app, event)
            value = app.OffsetmVKnob.Value;
            app.servo.scan.offset.set(value/1e3);
            app.updateServo;
        end

        % Value changed function: AmplitudemVKnob
        function AmplitudemVKnobValueChanged(app, event)
            value = app.AmplitudemVKnob.Value;
            app.servo.scan.amplitude.set(value/1e3);
            app.servo.scan.setScanSteps;
            app.updateServo;
        end

        % Value changed function: EnableScanSwitch
        function EnableScanSwitchValueChanged(app, event)
            value = app.EnableScanSwitch.Value;
            if strcmpi(value,'off')
                value = 0;
            else
                value = 1;
            end
            app.servo.scan.enable.set(value);
            app.updateServo;
        end

        % Value changed function: ScanDurationmsEditField
        function ScanDurationmsEditFieldValueChanged(app, event)
            value = app.ScanDurationmsEditField.Value;
            app.servo.scan.setScanSteps(value/1e3);
            app.updateServo;
        end

        % Value changed function: RoutescantoDropDown
        function RoutescantoDropDownValueChanged(app, event)
            value = app.RoutescantoDropDown.Value;
            if strcmpi(value,'pid1')
                app.servo.pid(1).scanEnable.set(1);
                app.servo.pid(2).scanEnable.set(0);
            elseif strcmpi(value,'pid2')
                app.servo.pid(1).scanEnable.set(0);
                app.servo.pid(2).scanEnable.set(1);
            elseif strcmpi(value,'none')
                app.servo.pid(1).scanEnable.set(0);
                app.servo.pid(2).scanEnable.set(0);
            elseif strcmpi(value,'both')
                app.servo.pid(1).scanEnable.set(1);
                app.servo.pid(2).scanEnable.set(1);
            end
            app.updateServo;
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
            if strcmpi(value,'off')
                value = 0;
            else
                value = 1;
            end
            app.servo.pid(1).enable.set(value);
            app.updateServo;
        end

        % Value changed function: PolaritySwitch
        function PolaritySwitchValueChanged(app, event)
            value = app.PolaritySwitch.Value;
            if strcmpi(value,'negative')
                value = 0;
            elseif strcmpi(value,'positive')
                value = 1;
            end
            app.servo.pid(1).polarity.set(value);
            app.updateServo;
        end

        % Value changed function: ProportionalGainEditField
        function ProportionalGainEditFieldValueChanged(app, event)
            value = app.ProportionalGainEditField.Value;
            app.servo.pid(1).Kp.set(value);
            app.updateServo;
        end

        % Value changed function: IntegralGainEditField
        function IntegralGainEditFieldValueChanged(app, event)
            value = app.IntegralGainEditField.Value;
            app.servo.pid(1).Ki.set(value);
            app.updateServo;
        end

        % Value changed function: DerivativeGainEditField
        function DerivativeGainEditFieldValueChanged(app, event)
            value = app.DerivativeGainEditField.Value;
            app.servo.pid(1).Kd.set(value);
            app.updateServo;
        end

        % Value changed function: DivisorEditField
        function DivisorEditFieldValueChanged(app, event)
            value = app.DivisorEditField.Value;
            app.servo.pid(1).divisor.set(value);
            app.updateServo;
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            value = app.UpperLimitEditField.Value;
            app.servo.pid(1).upperLimit.set(value);
            app.updateServo;
        end

        % Value changed function: SetPointEditField
        function SetPointEditFieldValueChanged(app, event)
            value = app.SetPointEditField.Value;
            app.servo.pid(1).control.set(value);
            app.updateServo;
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            value = app.LowerLimitEditField.Value;
            app.servo.pid(1).lowerLimit.set(value);
            app.updateServo;
        end

        % Value changed function: EnableSwitch_2
        function EnableSwitch_2ValueChanged(app, event)
            value = app.EnableSwitch_2.Value;
            if strcmpi(value,'off')
                value = 0;
            else
                value = 1;
            end
            app.servo.pid(2).enable.set(value);
            app.updateServo;
        end

        % Value changed function: PolaritySwitch_2
        function PolaritySwitch_2ValueChanged(app, event)
            value = app.PolaritySwitch_2.Value;
            if strcmpi(value,'negative')
                value = 0;
            elseif strcmpi(value,'positive')
                value = 1;
            end
            app.servo.pid(2).polarity.set(value);
            app.updateServo;
        end

        % Value changed function: ProportionalGainEditField_2
        function ProportionalGainEditField_2ValueChanged(app, event)
            value = app.ProportionalGainEditField_2.Value;
            app.servo.pid(2).Kp.set(value);
            app.updateServo;
        end

        % Value changed function: IntegralGainEditField_2
        function IntegralGainEditField_2ValueChanged(app, event)
            value = app.IntegralGainEditField_2.Value;
            app.servo.pid(2).Ki.set(value);
            app.updateServo;
        end

        % Value changed function: DerivativeGainEditField_2
        function DerivativeGainEditField_2ValueChanged(app, event)
            value = app.DerivativeGainEditField_2.Value;
            app.servo.pid(2).Kd.set(value);
            app.updateServo;
        end

        % Value changed function: DivisorEditField_2
        function DivisorEditField_2ValueChanged(app, event)
            value = app.DivisorEditField_2.Value;
            app.servo.pid(2).divisor.set(value);
            app.updateServo;
        end

        % Value changed function: UpperLimitEditField_2
        function UpperLimitEditField_2ValueChanged(app, event)
            value = app.UpperLimitEditField_2.Value;
            app.servo.pid(2).upperLimit.set(value);
            app.updateServo;
        end

        % Value changed function: SetPointEditField_2
        function SetPointEditField_2ValueChanged(app, event)
            value = app.SetPointEditField_2.Value;
            app.servo.pid(2).control.set(value);
            app.updateServo;
        end

        % Value changed function: LowerLimitEditField_2
        function LowerLimitEditField_2ValueChanged(app, event)
            value = app.LowerLimitEditField_2.Value;
            app.servo.pid(2).lowerLimit.set(value);
            app.updateServo;
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            value = app.AutoUpdateSwitch.Value;
            if strcmpi(value,'off')
                app.autoUpdate = 0;
            else
                app.autoUpdate = 1;
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.servo.upload;
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            app.servo.fetch;
            app.updateDisplay;
        end

        % Button pushed function: FetchDataButton
        function FetchDataButtonPushed(app, event)
            app.updatePlot;
        end

        % Value changed function: OffsetField
        function OffsetFieldValueChanged(app, event)
            value = app.OffsetField.Value;
            app.servo.scan.offset.set(value/1e3);
            app.updateServo;
        end

        % Value changed function: AmplitudeField
        function AmplitudeFieldValueChanged(app, event)
            value = app.AmplitudeField.Value;
            app.servo.scan.amplitude.set(value/1e3);
            app.updateServo;
        end

        % Value changed function: RetrievescandataSwitch
        function RetrievescandataSwitchValueChanged(app, event)
            value = app.RetrievescandataSwitch.Value;
            if strcmpi(value,'off')
                if isa(app.int_timer,'timer')
                    stop(app.int_timer);
                    delete(app.int_timer);
                end
            elseif strcmpi(value,'on')
                app.int_timer = timer('ExecutionMode','fixedSpacing','Name','Scan Retrieval timer','Period',app.PlotUpdateTimesEditField.Value);
                app.int_timer.ErrorFcn = @(obj,event) delete(obj);
                app.int_timer.TimerFcn = @(~,~) app.updatePlot;
                start(app.int_timer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ScanControlPanel = uipanel(app.UIFigure);
            app.ScanControlPanel.Title = 'Scan Control';
            app.OffsetmVKnobLabel = uilabel(app.ScanControlPanel);
            app.OffsetmVKnobLabel.Text = 'Offset (mV)';
            app.OffsetmVKnob = uiknob(app.ScanControlPanel, 'continuous');
            app.OffsetmVKnob.Limits = [-1000 1000];
            app.OffsetmVKnob.ValueChangedFcn = createCallbackFcn(app, @OffsetmVKnobValueChanged, true);
            app.AmplitudemVKnobLabel = uilabel(app.ScanControlPanel);
            app.AmplitudemVKnobLabel.Text = 'Amplitude (mV)';
            app.AmplitudemVKnob = uiknob(app.ScanControlPanel, 'continuous');
            app.AmplitudemVKnob.Limits = [0 1000];
            app.AmplitudemVKnob.MajorTicks = [0 250 500 750 1000];
            app.AmplitudemVKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudemVKnobValueChanged, true);
            app.EnableScanSwitchLabel = uilabel(app.ScanControlPanel);
            app.EnableScanSwitchLabel.Text = 'Enable Scan';
            app.EnableScanSwitch = uiswitch(app.ScanControlPanel, 'slider');
            app.EnableScanSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableScanSwitchValueChanged, true);
            app.ScanDurationmsEditFieldLabel = uilabel(app.ScanControlPanel);
            app.ScanDurationmsEditFieldLabel.Text = 'Scan Duration (ms)';
            app.ScanDurationmsEditField = uieditfield(app.ScanControlPanel, 'numeric');
            app.ScanDurationmsEditField.Limits = [0 Inf];
            app.ScanDurationmsEditField.ValueDisplayFormat = '%.1f';
            app.ScanDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @ScanDurationmsEditFieldValueChanged, true);
            app.ScanDurationmsEditField.Value = 50;
            app.RoutescantoDropDownLabel = uilabel(app.ScanControlPanel);
            app.RoutescantoDropDownLabel.Text = 'Route scan to';
            app.RoutescantoDropDown = uidropdown(app.ScanControlPanel);
            app.RoutescantoDropDown.Items = {'None', 'PID1', 'PID2', 'Both'};
            app.RoutescantoDropDown.ValueChangedFcn = createCallbackFcn(app, @RoutescantoDropDownValueChanged, true);
            app.RoutescantoDropDown.Value = 'PID1';
            app.OffsetField = uieditfield(app.ScanControlPanel, 'numeric');
            app.OffsetField.ValueDisplayFormat = '%.2f';
            app.OffsetField.ValueChangedFcn = createCallbackFcn(app, @OffsetFieldValueChanged, true);
            app.AmplitudeField = uieditfield(app.ScanControlPanel, 'numeric');
            app.AmplitudeField.ValueDisplayFormat = '%.2f';
            app.AmplitudeField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeFieldValueChanged, true);
            app.FilteringSettingsPanel = uipanel(app.UIFigure);
            app.FilteringSettingsPanel.Title = 'Filtering Settings';
            app.Log2ofavgsSpinnerLabel = uilabel(app.FilteringSettingsPanel);
            app.Log2ofavgsSpinnerLabel.Text = 'Log2 # of avgs';
            app.Log2ofavgsSpinner = uispinner(app.FilteringSettingsPanel);
            app.Log2ofavgsSpinner.Limits = [0 Inf];
            app.Log2ofavgsSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2ofavgsSpinnerValueChanged, true);
            app.Log2ofavgsSpinner.Value = 4;
            app.dtsEditFieldLabel = uilabel(app.FilteringSettingsPanel);
            app.dtsEditFieldLabel.Text = 'dt [s]';
            app.dtsEditField = uieditfield(app.FilteringSettingsPanel, 'numeric');
            app.dtsEditField.ValueDisplayFormat = '%.2e';
            app.dtsEditField.Editable = 'off';
            app.BWHzEditFieldLabel = uilabel(app.FilteringSettingsPanel);
            app.BWHzEditFieldLabel.Text = 'BW [Hz]';
            app.BWHzEditField = uieditfield(app.FilteringSettingsPanel, 'numeric');
            app.BWHzEditField.ValueDisplayFormat = '%.2e';
            app.BWHzEditField.Editable = 'off';
            app.DataCollectionPanel = uipanel(app.UIFigure);
            app.DataCollectionPanel.Title = 'Data Collection';
            app.FIFO1DropDownLabel = uilabel(app.DataCollectionPanel);
            app.FIFO1DropDownLabel.Text = 'FIFO 1';
            app.FIFO1DropDown = uidropdown(app.DataCollectionPanel);
            app.FIFO1DropDown.Items = {'adc1', 'adc2', 'scan', 'pid1', 'pid2', 'act1', 'act2'};
            app.FIFO1DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO1DropDownValueChanged, true);
            app.FIFO1DropDown.Value = 'adc1';
            app.FIFO2DropDownLabel = uilabel(app.DataCollectionPanel);
            app.FIFO2DropDownLabel.Text = 'FIFO 2';
            app.FIFO2DropDown = uidropdown(app.DataCollectionPanel);
            app.FIFO2DropDown.Items = {'adc1', 'adc2', 'scan', 'pid1', 'pid2', 'act1', 'act2'};
            app.FIFO2DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO2DropDownValueChanged, true);
            app.FIFO2DropDown.Value = 'scan';
            app.PID1Panel = uipanel(app.UIFigure);
            app.PID1Panel.Title = 'PID 1';
            app.EnableSwitchLabel = uilabel(app.PID1Panel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.PID1Panel, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.PolaritySwitchLabel = uilabel(app.PID1Panel);
            app.PolaritySwitchLabel.Text = 'Polarity';
            app.PolaritySwitch = uiswitch(app.PID1Panel, 'slider');
            app.PolaritySwitch.Items = {'Negative', 'Positive'};
            app.PolaritySwitch.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitchValueChanged, true);
            app.PolaritySwitch.Value = 'Negative';
            app.ProportionalGainEditFieldLabel = uilabel(app.PID1Panel);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditField = uieditfield(app.PID1Panel, 'numeric');
            app.ProportionalGainEditField.Limits = [0 Inf];
            app.ProportionalGainEditField.RoundFractionalValues = 'on';
            app.ProportionalGainEditField.ValueDisplayFormat = '%d';
            app.ProportionalGainEditField.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldValueChanged, true);
            app.IntegralGainEditFieldLabel = uilabel(app.PID1Panel);
            app.IntegralGainEditFieldLabel.Text = 'Integral Gain';
            app.IntegralGainEditField = uieditfield(app.PID1Panel, 'numeric');
            app.IntegralGainEditField.Limits = [0 Inf];
            app.IntegralGainEditField.RoundFractionalValues = 'on';
            app.IntegralGainEditField.ValueDisplayFormat = '%d';
            app.IntegralGainEditField.ValueChangedFcn = createCallbackFcn(app, @IntegralGainEditFieldValueChanged, true);
            app.DerivativeGainEditFieldLabel = uilabel(app.PID1Panel);
            app.DerivativeGainEditFieldLabel.Text = 'Derivative Gain';
            app.DerivativeGainEditField = uieditfield(app.PID1Panel, 'numeric');
            app.DerivativeGainEditField.Limits = [0 Inf];
            app.DerivativeGainEditField.RoundFractionalValues = 'on';
            app.DerivativeGainEditField.ValueDisplayFormat = '%d';
            app.DerivativeGainEditField.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditFieldValueChanged, true);
            app.DivisorEditFieldLabel = uilabel(app.PID1Panel);
            app.DivisorEditFieldLabel.Text = 'Divisor';
            app.DivisorEditField = uieditfield(app.PID1Panel, 'numeric');
            app.DivisorEditField.Limits = [0 Inf];
            app.DivisorEditField.RoundFractionalValues = 'on';
            app.DivisorEditField.ValueDisplayFormat = '%d';
            app.DivisorEditField.ValueChangedFcn = createCallbackFcn(app, @DivisorEditFieldValueChanged, true);
            app.RealKpEditField = uieditfield(app.PID1Panel, 'numeric');
            app.RealKpEditField.Editable = 'off';
            app.RealKiEditField = uieditfield(app.PID1Panel, 'numeric');
            app.RealKiEditField.Editable = 'off';
            app.RealKdEditField = uieditfield(app.PID1Panel, 'numeric');
            app.RealKdEditField.Editable = 'off';
            app.UpperLimitEditFieldLabel = uilabel(app.PID1Panel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.PID1Panel, 'numeric');
            app.UpperLimitEditField.ValueDisplayFormat = '%4.4g';
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.SetPointEditField_2Label = uilabel(app.PID1Panel);
            app.SetPointEditField_2Label.Text = 'Set Point';
            app.SetPointEditField = uieditfield(app.PID1Panel, 'numeric');
            app.SetPointEditField.ValueDisplayFormat = '%4.4g';
            app.SetPointEditField.ValueChangedFcn = createCallbackFcn(app, @SetPointEditFieldValueChanged, true);
            app.LowerLimitEditFieldLabel = uilabel(app.PID1Panel);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.PID1Panel, 'numeric');
            app.LowerLimitEditField.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.PID2Panel = uipanel(app.UIFigure);
            app.PID2Panel.Title = 'PID 2';
            app.EnableSwitch_2Label = uilabel(app.PID2Panel);
            app.EnableSwitch_2Label.Text = 'Enable';
            app.EnableSwitch_2 = uiswitch(app.PID2Panel, 'slider');
            app.EnableSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EnableSwitch_2ValueChanged, true);
            app.PolaritySwitch_2Label = uilabel(app.PID2Panel);
            app.PolaritySwitch_2Label.Text = 'Polarity';
            app.PolaritySwitch_2 = uiswitch(app.PID2Panel, 'slider');
            app.PolaritySwitch_2.Items = {'Negative', 'Positive'};
            app.PolaritySwitch_2.ValueChangedFcn = createCallbackFcn(app, @PolaritySwitch_2ValueChanged, true);
            app.PolaritySwitch_2.Value = 'Negative';
            app.ProportionalGainEditField_2Label = uilabel(app.PID2Panel);
            app.ProportionalGainEditField_2Label.Text = 'Proportional Gain';
            app.ProportionalGainEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.ProportionalGainEditField_2.Limits = [0 Inf];
            app.ProportionalGainEditField_2.RoundFractionalValues = 'on';
            app.ProportionalGainEditField_2.ValueDisplayFormat = '%d';
            app.ProportionalGainEditField_2.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditField_2ValueChanged, true);
            app.IntegralGainEditField_2Label = uilabel(app.PID2Panel);
            app.IntegralGainEditField_2Label.Text = 'Integral Gain';
            app.IntegralGainEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.IntegralGainEditField_2.Limits = [0 Inf];
            app.IntegralGainEditField_2.RoundFractionalValues = 'on';
            app.IntegralGainEditField_2.ValueDisplayFormat = '%d';
            app.IntegralGainEditField_2.ValueChangedFcn = createCallbackFcn(app, @IntegralGainEditField_2ValueChanged, true);
            app.DerivativeGainEditField_2Label = uilabel(app.PID2Panel);
            app.DerivativeGainEditField_2Label.Text = 'Derivative Gain';
            app.DerivativeGainEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.DerivativeGainEditField_2.Limits = [0 Inf];
            app.DerivativeGainEditField_2.RoundFractionalValues = 'on';
            app.DerivativeGainEditField_2.ValueDisplayFormat = '%d';
            app.DerivativeGainEditField_2.ValueChangedFcn = createCallbackFcn(app, @DerivativeGainEditField_2ValueChanged, true);
            app.DivisorEditField_2Label = uilabel(app.PID2Panel);
            app.DivisorEditField_2Label.Text = 'Divisor';
            app.DivisorEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.DivisorEditField_2.Limits = [0 Inf];
            app.DivisorEditField_2.RoundFractionalValues = 'on';
            app.DivisorEditField_2.ValueDisplayFormat = '%d';
            app.DivisorEditField_2.ValueChangedFcn = createCallbackFcn(app, @DivisorEditField_2ValueChanged, true);
            app.RealKpEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.RealKpEditField_2.Editable = 'off';
            app.RealKiEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.RealKiEditField_2.Editable = 'off';
            app.RealKdEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.RealKdEditField_2.Editable = 'off';
            app.UpperLimitEditField_2Label = uilabel(app.PID2Panel);
            app.UpperLimitEditField_2Label.Text = 'Upper Limit';
            app.UpperLimitEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.UpperLimitEditField_2.ValueDisplayFormat = '%4.4g';
            app.UpperLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditField_2ValueChanged, true);
            app.SetPointEditField_3Label = uilabel(app.PID2Panel);
            app.SetPointEditField_3Label.Text = 'Set Point';
            app.SetPointEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.SetPointEditField_2.ValueDisplayFormat = '%4.4g';
            app.SetPointEditField_2.ValueChangedFcn = createCallbackFcn(app, @SetPointEditField_2ValueChanged, true);
            app.LowerLimitEditField_2Label = uilabel(app.PID2Panel);
            app.LowerLimitEditField_2Label.Text = 'Lower Limit';
            app.LowerLimitEditField_2 = uieditfield(app.PID2Panel, 'numeric');
            app.LowerLimitEditField_2.ValueDisplayFormat = '%4.4g';
            app.LowerLimitEditField_2.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditField_2ValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scan Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ApplicationSettingsPanel = uipanel(app.UIFigure);
            app.ApplicationSettingsPanel.Title = 'Application Settings';
            app.AutoUpdateSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.AutoUpdateSwitchLabel.Text = 'Auto-Update';
            app.AutoUpdateSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.UploadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FetchButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.RetrievescandataSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.RetrievescandataSwitchLabel.Text = 'Retrieve scan data';
            app.RetrievescandataSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.RetrievescandataSwitch.ValueChangedFcn = createCallbackFcn(app, @RetrievescandataSwitchValueChanged, true);
            app.FetchDataButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchDataButton.ButtonPushedFcn = createCallbackFcn(app, @FetchDataButtonPushed, true);
            app.FetchDataButton.Text = 'Fetch Data';
            app.PlotUpdateTimesEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.PlotUpdateTimesEditFieldLabel.Text = 'Plot Update Time [s]';
            app.PlotUpdateTimesEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.PlotUpdateTimesEditField.Limits = [0 Inf];
            app.PlotUpdateTimesEditField.ValueDisplayFormat = '%4.2g';
            app.PlotUpdateTimesEditField.Value = 0.1;
            app.PlotDropDown = uidropdown(app.ApplicationSettingsPanel);
            app.PlotDropDown.Items = {'Time', 'Volts', 'PSD'};
            app.PlotDropDown.Value = 'Time';
            app.XAxisDropDownLabel = uilabel(app.ApplicationSettingsPanel);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.ApplicationSettingsPanel);
            app.XAxisDropDown.Items = {'Lin', 'Log'};
            app.XAxisDropDown.Value = 'Lin';
            app.YAxisDropDownLabel = uilabel(app.ApplicationSettingsPanel);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.ApplicationSettingsPanel);
            app.YAxisDropDown.Items = {'Lin', 'Log'};
            app.YAxisDropDown.Value = 'Lin';
            app.YLowerLimEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.YLowerLimEditFieldLabel.Text = 'Y Lower Lim';
            app.YLowerLimEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.YLowerLimEditField.Value = -Inf;
            app.YUpperLimEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.YUpperLimEditFieldLabel.Text = 'Y Upper Lim';
            app.YUpperLimEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.YUpperLimEditField.Value = Inf;
            app.PlotDisplayDropDown = uidropdown(app.ApplicationSettingsPanel);
            app.PlotDisplayDropDown.Items = {'FIFO 1', 'FIFO 2', 'Both', 'Option 4'};
            app.PlotDisplayDropDown.Value = 'Both';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end